classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ExportDisplacementDatatoExcelButton  matlab.ui.control.Button
        ExportVelocityDatatoExcelButton  matlab.ui.control.Button
        ExportAccelerationDatatoExcelButton  matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        VelocitykmphEditField       matlab.ui.control.NumericEditField
        VelocitykmphEditFieldLabel  matlab.ui.control.Label
        CoachNum                    matlab.ui.control.Spinner
        NumberofCoachesExcludingLocomotivesSpinnerLabel  matlab.ui.control.Label
        SelectModelTrainPropertiesButtonGroup  matlab.ui.container.ButtonGroup
        Shinkansen                  matlab.ui.control.RadioButton
        PoineerR                    matlab.ui.control.RadioButton
        PoineerM                    matlab.ui.control.RadioButton
        Chinese                     matlab.ui.control.RadioButton
        Eurostar                    matlab.ui.control.RadioButton
        AVE                         matlab.ui.control.RadioButton
        Manchester                  matlab.ui.control.RadioButton
        RunButton                   matlab.ui.control.Button
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
% Main script

VelocitykmphEditFieldValueChanged(app,[]);
clc;

% --------------------------- Definitions ---------------------------------


% ---- Train ----
% Definitions for Train

% Velocity
Train.vel = (app.VelocitykmphEditField.Value)/3.6;          % Train velocity [m/s]

% -- Mechanical properties --

% File loading path
Train.Load.path = '';

% Defnintion of successive vehicles
trmodel = app.SelectModelTrainPropertiesButtonGroup.SelectedObject;
veh_num = 0;
if trmodel == app.Manchester
    veh_num = 1; run([Train.Load.path,'TrainProp_Manchester_BenchMark']);
    for i = 1 : app.CoachNum.Value
        veh_num = i+1; run([Train.Load.path,'TrainProp_Manchester_BenchMark']);
    end
elseif trmodel == app.AVE
    veh_num = 1; run([Train.Load.path,'TrainProp_AVE_S103_ICE3_Velaro_E_C1']);
    for i = 1 : app.CoachNum.Value
        veh_num = i+1; 
        randomCoach = randi(2);
        if randomCoach == 1
            run([Train.Load.path,'TrainProp_AVE_S103_ICE3_Velaro_E_C2367']);
        else
            run([Train.Load.path,'TrainProp_AVE_S103_ICE3_Velaro_E_C45']);
        end
    end
    veh_num = app.CoachNum.Value+1; run([Train.Load.path,'TrainProp_AVE_S103_ICE3_Velaro_E_C8']);
elseif trmodel == app.Eurostar
    veh_num = 1; run([Train.Load.path,'TrainProp_Eurostar_Locomotive']);
    veh_num = 2; run([Train.Load.path,'TrainProp_Eurostar_EC1']);
    for i = 1 : app.CoachNum.Value
        veh_num = i+2; run([Train.Load.path,'TrainProp_Eurostar_EC2_EC8']);
    end
    veh_num = app.CoachNum.Value+3; run([Train.Load.path,'TrainProp_Eurostar_EC9']);
    veh_num = app.CoachNum.Value+4; run([Train.Load.path,'TrainProp_Eurostar_Locomotive']);
elseif trmodel == app.Chinese
    veh_num = 1; run([Train.Load.path,'TrainProp_ChineseStarPowerCar']);
    for i = 1 : app.CoachNum.Value
        veh_num = i+1; run([Train.Load.path,'TrainProp_DoubleDeckPassengerCoach']);
    end
elseif trmodel == app.PoineerM
    veh_num = 1; run([Train.Load.path,'TrainProp_Pioneer_M_vehicle']);
    for i = 1 : app.CoachNum.Value
        veh_num = i+1; run([Train.Load.path,'TrainProp_Pioneer_M_vehicle']);
    end
elseif trmodel == app.PoineerR
    veh_num = 1; run([Train.Load.path,'TrainProp_Pioneer_R_vehicle']);
    for i = 1 : app.CoachNum.Value
        veh_num = i+1; run([Train.Load.path,'TrainProp_Pioneer_R_vehicle']);
    end
elseif trmodel == app.Shinkansen
    veh_num = 1; run([Train.Load.path,'TrainProp_ShinkansenS300']);
    for i = 1 : app.CoachNum.Value
        veh_num = i+1; run([Train.Load.path,'TrainProp_ShinkansenS300']);
    end    
end


% Copy of Train.vel
Train.Veh(1).vel = Train.vel;

clear veh_num

% ---- End of script ----


% ---- Track ----
% Definition of Track

% -- Mechanical properties --

% File loading path
Track.Load.path = '';

% Loading predefined list of track properties

% Possibility 1: With Ballast on Bridge
run([Track.Load.path,'TrackProp_Zhai_et_al_WithBallastOnBridge']);

% % Possibility 2: No Ballast on Bridge
% run([Track.Load.path,'TrackProp_Zhai_et_al_NoBallastOnBridge']);

% -- End of script --


% ---- Bridge ----
% Bridge model definition

% Not a function.
% Defines the content of the variable Beam.

% A 50m bridge. Properties taken from:
% He Xia, Nan Zhang, Guido De Roeck, Dynamic analysis of high speed railway 
%   bridge under articulated trains, Computers & Structures, Volume 81, 
%   Issues 26–27, 2003, Pages 2467-2478, ISSN 0045-7949,
%   https://doi.org/10.1016/S0045-7949(03)00309-2.
Beam.Prop.L = 50;       % Span [m]
Beam.Prop.E = 35e9;     % Modulus of elasticity [N/m^2]
Beam.Prop.I = 51.3;     % Second moment of area [m4]
Beam.Damping.per = 1;   % Damping [%]
Beam.Prop.rho = 69000;  % Mass per unit length [kg/m]

% Boundary conditions
Beam.BC.text = 'SP';    % Simply supported
%Beam.BC.text = 'FF';    % Fixed-fixed

% % Boundary conditions - Alternative definitions
% Beam.BC.loc = [0,Beam.Prop.L/2,Beam.Prop.L];  % Location of supports
% Beam.BC.vert_stiff = [-1,-1,-1];              % Vertical stiffness of supports (-1 for perfectly stiff)
% Beam.BC.rot_stiff = [0,0,0];                  % Rotational stiffness of supports (-1 for perfectly stiff)
% Beam.BC.text = 'User-defined';
% Beam.BC.text_long = '2-span bridge';

% ---- End of script ---


% ---- Options ----
% Definition of addition relevant aspects of the model and calculation options

% Not a function.
% Defines the content of the variable Calc, 
%   and modifies the content of variables Beam and Track.

% ------------------------- Track irregularity ----------------------------

% ---- Smooth ---- (Default)
Calc.Profile.Type = 0;

% ---- FRA ---- 
% Reference: Ladislav Fryba. Dynamics of Railway Bridges. Academia Praha, 1996
Calc.Profile.Type = 1;      % Defined from PSD [m^3]
Calc.Profile.PSD_Y_fun = @(spaf,inputs) ...
    inputs(1)*inputs(3)^2*(spaf.^(2)+inputs(2)^2)./(spaf.^(4).*(spaf.^(2)+inputs(3)^2));
%Calc.Profile.inputs(1) = 15.53*1e-8;    % A [m^3] Class 1
%Calc.Profile.inputs(1) = 8.85*1e-8;     % A [m^3] Class 2
%Calc.Profile.inputs(1) = 4.92*1e-8;     % A [m^3] Class 3
%Calc.Profile.inputs(1) = 2.75*1e-8;     % A [m^3] Class 4
%Calc.Profile.inputs(1) = 1.57*1e-8;     % A [m^3] Class 5
Calc.Profile.inputs(1) = 0.98*1e-8;     % A [m^3] Class 6
Calc.Profile.inputs(2) = 23.3*1e-3;     % Omega1 [1/m]
Calc.Profile.inputs(3) = 13.1*1e-2;     % Omega2 [1/m]
Calc.Profile.min_WaveLength = 1.524;    % [m]
Calc.Profile.max_WaveLength = 304.8;    % [m]
Calc.Profile.text = 'FRA';

% % ---- FRA version 2 ----
% % Reference: Abdur Rohim Boy Berawi. Improving railway track maintenance
% %     using power spectral density (PSD). Doctoral thesis.
% %     Link: https://sigarra.up.pt/feup/en/pub_geral.show_file?pi_doc_id=12113
% Calc.Profile.Type = 1;  % Defined from PSD [m^3]
% Calc.Profile.PSD_Y_fun = @(spaf,inputs) (inputs(1)*inputs(2)*inputs(3)^2)./((spaf.^2).*(spaf.^2+inputs(3)^2))*inputs(4);
% Calc.Profile.inputs(1) = 0.25;          % k = Some constant
% %Calc.Profile.inputs(2) = 1.2107;   % A_v [cm^2 rad/m] Class 1
% %Calc.Profile.inputs(2) = 1.0181;   % A_v [cm^2 rad/m] Class 2
% %Calc.Profile.inputs(2) = 0.6816;   % A_v [cm^2 rad/m] Class 3
% %Calc.Profile.inputs(2) = 0.5376;   % A_v [cm^2 rad/m] Class 4
% %Calc.Profile.inputs(2) = 0.2095;   % A_v [cm^2 rad/m] Class 5
% Calc.Profile.inputs(2) = 0.0339;   % A_v [cm^2 rad/m] Class 6
% Calc.Profile.inputs(3) = 0.8245;   % Omega_c [rad/m]
% Calc.Profile.inputs(4) = 1e-4/(2*pi);   % Unit conversion [cm^2 = 10^(-4) m^2] and [rad = 1/(2*pi) cycles]
% Calc.Profile.min_WaveLength = 1.524;    % [m]
% Calc.Profile.max_WaveLength = 304.8;    % [m]
% Calc.Profile.text = 'FRAv2';

% % ---- German spectra for high-speed ----
% % Reference: W.W. Guo, H. Xia, G. De Roeck, K. Liu, Integral model for 
% %     train-track-bridge interaction on the Sesia viaduct: Dynamic simulation 
% %     and critical assessment, Computers & Structures, Volumes 112–113,
% %     2012, Pages 205-216, ISSN 0045-7949,
% %     https://doi.org/10.1016/j.compstruc.2012.09.001.
% Calc.Profile.Type = 1;  % Defined from PSD [m^3]
% Calc.Profile.PSD_Y_fun = @(spaf,inputs) (inputs(1)*inputs(2)^2)./((spaf.^2+inputs(3)^2).*(spaf.^2+inputs(2)^2))*inputs(4);
% Calc.Profile.inputs(1) = 4.032e-7*1/(2*pi)*(0.4/0.9843)^2;  % A_v [m^2 rad/m]
% Calc.Profile.inputs(2) = 0.8246*1/(2*pi);                   % Omega_c [rad/m]
% Calc.Profile.inputs(3) = 0.0206*1/(2*pi);                   % Omega_T [rad/m]
% Calc.Profile.inputs(4) = 1;                         % Unit conversion [rad = 1/(2*pi) cycles]
% Calc.Profile.min_WaveLength = 3;                    % [m]
% Calc.Profile.max_WaveLength = 150;                  % [m]
% Calc.Profile.text = 'German high-speed';

% % ---- SNCF ----
% % Reference: Abdur Rohim Boy Berawi. Improving railway track maintenance
% %     using power spectral density (PSD). Doctoral thesis.
% %     Link: https://sigarra.up.pt/feup/en/pub_geral.show_file?pi_doc_id=12113
% Calc.Profile.Type = 1;  % Defined from PSD [m^3]
% Calc.Profile.PSD_Y_fun = @(spaf,inputs) inputs(1)./(1+spaf/inputs(2)).^3;
% Calc.Profile.inputs(1) = 0.509*308*10^-6;       % A for good track
% %Calc.Profile.inputs(1) = 1.790*308*10^-6;      % A for poor track
% Calc.Profile.inputs(2) = 0.0489;                % n0 [1/m]
% Calc.Profile.min_WaveLength = 2;                % [m]
% Calc.Profile.max_WaveLength = 40;               % [m]
% Calc.Profile.text = 'SNCF';

% % ---- Load existing profile ----
% Calc.Profile.Type = 5;
% Calc.Profile.Load.file_name = '20171207_FRA_Class6_500m';
% Calc.Profile.Load.path_name = 'Results\Profiles\';

% --------------------------- Mesh Properties -----------------------------

% -- Element size for Bridge model --
Beam.Mesh.Ele.num_per_spacing = 2;

% -- Element size for rail in Track model --
Track.Rail.Mesh.Ele.num_per_spacing = 2;

% -- Approach distance --
Calc.Profile.minL_Approach = 30;    % Approach distance [m]

% -- Distance after crossing -- (To allow for free vibration)
Calc.Profile.minL_After = 30;   % [m] (Default value = 10)

% ----------------- Calculation options and variables ---------------------

% Results at particular bridge sections
Calc.Options.calc_beam_sections = [Beam.Prop.L/2];      % (Default = [])

% Time step
Calc.Solver.max_accurate_frq = 500;     % [Hz]

% Consider VBI
Calc.Options.VBI = 1;        % VBI is considered (default)
%Calc.Options.VBI = 0;        % No VBI considered = Moving force solution 

% Redux model option
Calc.Options.redux = 0;         % Original script. Track under the vehicle
%Calc.Options.redux = 1;         % No track under vehicle at start and end of simulation (Default)

% Other
Calc.Options.calc_beam_modes = 0;   % In modal analysis, no modes are calculated

% -------------------------------------------------------------------------
% ------------------------- Plotting Options ------------------------------

% Note: Comment/uncomment to switch off/on the corresponding plot

% ---- Vehicle ----
%Calc.Plot.Veh.P01_VertDisp = 1;         % Vehicle vertical diplacements
%Calc.Plot.Veh.P02_VertVel = 1;          % Vehicle vertical velocities
Calc.Plot.Veh.P03_VertAcc = 1;          % Vehicle vertical accelerations
Calc.Plot.Veh.P04_ContactForce_t = 1;   % Vehicle vertical contact force in time (1 = Values; 2 = With 0 limit)
%Calc.Plot.Veh.P05_ContactForce_x = 1;   % Vehicle vertical contact force in space (1 = Values; 2 = With 0 limit)

% ---- Model ----
Calc.Plot.Model.P00_ModelVisualization = 1;% Sketch of model before performing simulation
%Calc.Plot.Model.P01_ModelDef = 50;       % Model vertical deformation at X% of the total simulated time
%Calc.Plot.Model.P02_ModelRot = 10;       % Model rotation values at X% of the total simulated time

% ---- Beam ----
%Calc.Plot.Beam.P01_DispContour = 1;          % Beam displacement contour plot
Calc.Plot.Beam.P02_StaticDispContour = 1;    % Beam static displacment contour plot
Calc.Plot.Beam.P03_BMContour = 1;            % Beam Bending moment contour plot
%Calc.Plot.Beam.P04_StaticBMContour = 1;      % Beam Static Bending moment contour plot
%Calc.Plot.Beam.P05_ShearContour = 1;         % Beam Shear contour plot
%Calc.Plot.Beam.P06_StaticShearContour = 1;   % Beam Static Shear contour plot
%Calc.Plot.Beam.P07_VertAccContour = 1;       % Beam Vertial Acceleration contour plot
%Calc.Plot.Beam.P08_Sections_BeamVertDisp = 1;% Beam vertical displacement at selected sections
%Calc.Plot.Beam.P09_Sections_BeamBM = 1;      % Beam Bending Moment at selected sections
%Calc.Plot.Beam.P10_Sections_BeamShear = 1;   % Beam Shear at selected sections
Calc.Plot.Beam.P11_Sections_BeamAcc = 1;     % Beam Acceleration at selected sections

% ---- Others ----
%Calc.Plot.Profile_original = 1;         % Generated Profile
%Calc.Plot.P3_VehPos = 1;                % Vehicle position in time

% ---- End of script ----


% --------------------------- Calculations --------------------------------

B00_Calculations;

%% -------------------- Results processing and plotting --------------------
%%------------App Designer-------------------
sp_or = Train.vel;
% Display the data
% numeric_data
t_per = 50;
% Selection of time-step to plot
Data = Sol.Model.Nodal.A;
t = round(Calc.Solver.num_t*t_per/100)+1-ceil(t_per/100);
x = linspace(1,round(500/sp_or),length( Model.Mesh.XLoc.rail_vert));
y = Data(Model.Mesh.DOF.rail_vert,t);
plot(app.UIAxes,x,y)
title(app.UIAxes,"Track Acceleration")


Data = Sol.Model.Nodal.V;
t = round(Calc.Solver.num_t*t_per/100)+1-ceil(t_per/100);
x = linspace(1,round(500/sp_or),length( Model.Mesh.XLoc.rail_vert));
y = Data(Model.Mesh.DOF.rail_vert,t);
plot(app.UIAxes2,x,y)
title(app.UIAxes2,"Track Velocity")

Data = Sol.Model.Nodal.U*1000;
t = round(Calc.Solver.num_t*t_per/100)+1-ceil(t_per/100);
x = linspace(1,round(500/sp_or),length( Model.Mesh.XLoc.rail_vert));
y = Data(Model.Mesh.DOF.rail_vert,t);
plot(app.UIAxes3,x,y)
title(app.UIAxes3,"Track Displacement")

%%---------------------------Matlab
% Model deformation plot at a given instant in time
C01_ModelDeformationPlot(Calc,Model,Sol,Train);

% % % Time history plot of user selected model point
C02_TimeHistoryPlot(Model,Sol,Beam,Calc);
% 
% % Other user-specified plots of the results
C03_TTB_2D_Plots;

% ---- End of script ----
        end

        % Value changed function: VelocitykmphEditField
        function VelocitykmphEditFieldValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SelectModelTrainPropertiesButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectModelTrainPropertiesButtonGroup.Title = 'Select Model (Train Properties)';
            app.Manchester = uiradiobutton(app.SelectModelTrainPropertiesButtonGroup);
            app.Manchester.Text = 'Manchester Benchmark';
            app.Manchester.Value = true;
            app.AVE = uiradiobutton(app.SelectModelTrainPropertiesButtonGroup);
            app.AVE.Text = 'AVE S103 ICE3 Velaro E';
            app.Eurostar = uiradiobutton(app.SelectModelTrainPropertiesButtonGroup);
            app.Eurostar.Text = 'Eurostar';
            app.Chinese = uiradiobutton(app.SelectModelTrainPropertiesButtonGroup);
            app.Chinese.Text = 'Chinese Star';
            app.PoineerM = uiradiobutton(app.SelectModelTrainPropertiesButtonGroup);
            app.PoineerM.Text = 'Poineer M';
            app.PoineerR = uiradiobutton(app.SelectModelTrainPropertiesButtonGroup);
            app.PoineerR.Text = 'Poineer R';
            app.Shinkansen = uiradiobutton(app.SelectModelTrainPropertiesButtonGroup);
            app.Shinkansen.Text = 'Shinkansen S300';
            app.NumberofCoachesExcludingLocomotivesSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofCoachesExcludingLocomotivesSpinnerLabel.Text = 'Number of Coaches (Excluding Locomotives)';
            app.CoachNum = uispinner(app.UIFigure);
            app.CoachNum.Limits = [1 20];
            app.CoachNum.Value = 1;
            app.VelocitykmphEditFieldLabel = uilabel(app.UIFigure);
            app.VelocitykmphEditFieldLabel.Text = 'Velocity (kmph)';
            app.VelocitykmphEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelocitykmphEditField.Limits = [10 200];
            app.VelocitykmphEditField.ValueChangedFcn = createCallbackFcn(app, @VelocitykmphEditFieldValueChanged, true);
            app.VelocitykmphEditField.Value = 10;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Note: Large Number of Coaches will take longer time for the program to execute.';
            app.ExportAccelerationDatatoExcelButton = uibutton(app.UIFigure, 'push');
            app.ExportAccelerationDatatoExcelButton.Text = 'Export Acceleration Data to Excel';
            app.ExportVelocityDatatoExcelButton = uibutton(app.UIFigure, 'push');
            app.ExportVelocityDatatoExcelButton.Text = 'Export Velocity Data to Excel';
            app.ExportDisplacementDatatoExcelButton = uibutton(app.UIFigure, 'push');
            app.ExportDisplacementDatatoExcelButton.Text = 'Export Displacement Data to Excel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end